classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Label     matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Button_2  matlab.ui.control.Button
        Panel     matlab.ui.container.Panel
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Label_3   matlab.ui.control.Label
        Label_4   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function Download(app, event)
            File = readtable('Product_dynamics.xlsx');
            app.Label.Visible = true;            
            pause(2)
            app.Label.Visible = false;
            app.Label_2.Visible = true;
            app.Button_2.Visible = true;
            app.Panel.Visible = true;
        end

        % Button pushed function: Button_2
        function disp_graph(app, event)
            File = readtable('Product_dynamics.xlsx');     
            x = [1;2;3;4;5;6;7;8;9;10;11;12];
            y = File.Rise;
            plot(x,y,'-o','LineWidth',1)
            grid on
            xticks(x)
            xticklabels(File.Data)
            title('Product Statistics')
            ylabel('Product Price (Tenge)')      
        end

        % Callback function
        function Maximum(app, event)
           
        end

        % Button pushed function: Button_4
        function Minimum_fun(app, event)
            
            File = readtable('Product_dynamics.xlsx');
            Minimum = ['min-for-Jan            ';'min-for-Jun,Feb        ';'min-for-Mar            ';'min-for-Apr            ';'min-for-May            ';'min-for-Apr,May,Jun    ';'min-for-Apr,May,Jun,Jul';'min-for-Aug            ';'min-for-Sep            ';'min-for-Aug,Sep,Oct    ';'min-for-Nov            ';'min-for-Dec            '];
            Minimum_1 = cellstr(Minimum);
            Report_min = splitvars(table(File,Minimum_1));            
            Report_min(1:12,:);
            writetable(Report_min,'Product_dynamics_min.xlsx','Sheet',1)
            app.Label_4.Visible = true
        end

        % Button pushed function: Button_3
        function Maximum_fun(app, event)
            
            File = readtable('Product_dynamics.xlsx');
            Maximum = ['max-for-Jan                                            ';'max-for-Feb                                            ';'max-for-Jan,Feb,Mar                                    ';'max-for-Jan,Feb,Mar,Apr                                ';'max-for-Jan,Feb,Mar,Apr,May                            ';'max-for-Jan,Feb,Mar,Jun                                ';'max-for-Jan,Feb,Mar,Jun,Jul                            ';'max-for-Jan,Feb,Mar,Apr,May,Jun,Jul,Aug                ';'max-for-Jan,Feb,Mar,Apr,May,Jun,Jul,Aug,Sep            ';'max-for-Jan,Feb,Mar,Apr,May,Jun,Jul,Oct                ';'max-for-Jan,Feb,Mar,Apr,May,Jun,Jul,Aug,Sep,Oct,Nov    ';'max-for-Jan,Feb,Mar,Apr,May,Jun,Jul,Aug,Sep,Oct,Nov,Dec'];
            Maximum_1 = cellstr(Maximum);
            Report_max = splitvars(table(File,Maximum_1));            
            Report_max(1:12,:);
            writetable(Report_max,'Product_dynamics_max.xlsx','Sheet',1)
            app.Label_3.Visible = true
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Download, true);
            app.Button.Text = 'Загрузить данные';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = 'Выполняется загрузка. Это займет несколько секунд...';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = 'Загрузка успешна завершена!';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @disp_graph, true);
            app.Button_2.Visible = 'off';
            app.Button_2.Text = 'Вывести график на экран';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Опции';
            app.Panel.Visible = 'off';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Maximum_fun, true);
            app.Button_3.Text = 'Определить максимум';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Minimum_fun, true);
            app.Button_4.Text = 'Определить минимум';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = 'Файл успешно создан! Проверьте его там же, где находится исходный файл';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = 'Файл успешно создан! Проверьте его там же, где находится исходный файл';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end