classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        Button    matlab.ui.control.Button
        Label     matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Button_2  matlab.ui.control.Button
        Panel     matlab.ui.container.Panel
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Label_3   matlab.ui.control.Label
        Label_4   matlab.ui.control.Label
        Button_5  matlab.ui.control.Button
        Button_6  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function Download_btn(app, event)
            File = readtable('Product_dynamics.xlsx');
            app.Label_2.Visible = true;            
            pause(2)
            app.Label_2.Visible = false;
            app.Label.Visible = true;
            app.Button_2.Visible = true;
            app.Panel.Visible = true;
        end

        % Button pushed function: Button_3
        function Maximum(app, event)
            File = readtable('Product_dynamics.xlsx');
            Max = [1;2;3;4;5;6;7;8;9;10;11;12];
            q = 2;
            w = 1;
            value = 0;
            e = 2;

            while q < 13    
                while w <= q
                    if File.Rise(q) >= File.Rise(w)        
                        value = value + 1;
                    else
                        value = value + 0;
                    end
                    w = w + 1;                
                end                
            Max(e) = value;
            w = 1;
            q = q + 1;
            e = e + 1;
            value = 0;
            end
            Final = splitvars(table(File,Max))
            writetable(Final,'Product_dynamics_max.xlsx','Sheet',1)
            app.Label_3.Visible = true
            app.Button_5.Visible = true
        end

        % Button pushed function: Button_4
        function Minimum(app, event)
            File = readtable('Product_dynamics.xlsx')
            Min = [1;2;3;4;5;6;7;8;9;10;11;12];
            q = 2;
            w = 1;
            value = 0;
            e = 2;

            while q < 13    
                while w <= q
                    if File.Rise(q) <= File.Rise(w)        
                        value = value + 1;
                    else
                        value = value + 0;
                    end
                    w = w + 1;                
                end                
            Min(e) = value;
            w = 1;
            q = q + 1;
            e = e + 1;
            value = 0;
            end
            Final = splitvars(table(File,Min))
            writetable(Final,'Product_dynamics_min.xlsx','Sheet',1)
            
            app.Label_4.Visible = true
            app.Button_6.Visible = true
        end

        % Button pushed function: Button_5
        function Open_max(app, event)
            winopen('Product_dynamics_max.xlsx')
        end

        % Button pushed function: Button_6
        function open_min(app, event)
            winopen('Product_dynamics_min.xlsx')
        end

        % Button pushed function: Button_2
        function Disp_graph(app, event)
            File = readtable('Product_dynamics.xlsx');     
            x = [1;2;3;4;5;6;7;8;9;10;11;12];
            y = File.Rise;
            plot(x,y,'-o','LineWidth',1)
            grid on
            xticks(x)
            xticklabels(File.Data)
            title('Product Statistics')
            ylabel('Product Price (Tenge)')      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'test.jpg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Download_btn, true);
            app.Button.Text = 'Загрузить данные';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = 'Загрузка успешна завершена!';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = 'Выполняется загрузка. Это займет несколько секунд...';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Disp_graph, true);
            app.Button_2.Visible = 'off';
            app.Button_2.Text = 'Вывести график на экран';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Опции';
            app.Panel.Visible = 'off';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Maximum, true);
            app.Button_3.Text = 'Определить максимум';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Minimum, true);
            app.Button_4.Text = 'Определить минимум';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = 'Файл успешно создан! Проверьте его там же, где находится исходный файл';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = 'Файл успешно создан! Проверьте его там же, где находится исходный файл';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Open_max, true);
            app.Button_5.Visible = 'off';
            app.Button_5.Text = 'Открыть';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @open_min, true);
            app.Button_6.Visible = 'off';
            app.Button_6.Text = 'Открыть';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end